classdef ES3H3Group2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GestureGuidePanel               matlab.ui.container.Panel
        Image_23                        matlab.ui.control.Image
        Image_22                        matlab.ui.control.Image
        Image_20                        matlab.ui.control.Image
        Image_19                        matlab.ui.control.Image
        Image_18                        matlab.ui.control.Image
        Image_17                        matlab.ui.control.Image
        Image_16                        matlab.ui.control.Image
        Image_15                        matlab.ui.control.Image
        Image_14                        matlab.ui.control.Image
        Image_13                        matlab.ui.control.Image
        Image_12                        matlab.ui.control.Image
        Image_7                         matlab.ui.control.Image
        Image_2                         matlab.ui.control.Image
        Image                           matlab.ui.control.Image
        ReturntoGesureRecogniserButton  matlab.ui.control.Button
        ReturnToMainMenuButton_6        matlab.ui.control.Button
        BCPanel                         matlab.ui.container.Panel
        GestureGuideButton              matlab.ui.control.Button
        BCStatusLabel                   matlab.ui.control.Label
        ReturnToMainMenuButton_2        matlab.ui.control.Button
        LiveAxes3                       matlab.ui.control.UIAxes
        TTTPanel                        matlab.ui.container.Panel
        ResetButton3                    matlab.ui.control.Button
        StatusLabel                     matlab.ui.control.Label
        TurnLamp                        matlab.ui.control.Lamp
        Label                           matlab.ui.control.Label
        B33                             matlab.ui.control.Button
        B32                             matlab.ui.control.Button
        B31                             matlab.ui.control.Button
        B23                             matlab.ui.control.Button
        B22                             matlab.ui.control.Button
        B21                             matlab.ui.control.Button
        B13                             matlab.ui.control.Button
        B12                             matlab.ui.control.Button
        B11                             matlab.ui.control.Button
        ReturnToMainMenuButton_4        matlab.ui.control.Button
        MainMenuPanel                   matlab.ui.container.Panel
        TTTButton                       matlab.ui.control.Button
        LHTButton                       matlab.ui.control.Button
        BCButton                        matlab.ui.control.Button
        RPSButton                       matlab.ui.control.Button
        RPSPanel                        matlab.ui.container.Panel
        ResultLabel                     matlab.ui.control.Label
        UserChoiceLabel                 matlab.ui.control.Label
        ComputerChoiceLabel             matlab.ui.control.Label
        SymbolImage                     matlab.ui.control.Image
        CountdownLabel                  matlab.ui.control.Label
        ResetButton                     matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        ReturnToMainMenuButton_5        matlab.ui.control.Button
        LiveAxes                        matlab.ui.control.UIAxes
        LHTPanel                        matlab.ui.container.Panel
        ResultLabel2                    matlab.ui.control.Label
        UserChoiceLabel2                matlab.ui.control.Label
        ComputerChoiceLabel2            matlab.ui.control.Label
        CountDownLabel                  matlab.ui.control.Label
        ResetButton2                    matlab.ui.control.Button
        StartButton2                    matlab.ui.control.Button
        SymbolImage2                    matlab.ui.control.Image
        ReturnToMainMenuButton_3        matlab.ui.control.Button
        LiveAxes2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam
        tmr
        step = 0
        slideIdx = 1
        slideImgs
        finalImgs
        countdownTexts
        countIdx
        countTimer
        finalPickIdx
        showFinalNextTick
        postShootTimer
        currentImageHandle
        currentLabelHandle
        currentAxesHandle
        choices
        turn = 1
        grid = zeros(3,3)
        gamemode = 1

        Buttons matlab.ui.control.Button

        rpsNet
        rpsInputSize
        rpsClassNames
        rpsAllowed
        rpsAllowedMask
        rpsUnknownThreshold = 0.70

        currentMode = "RPS"   % "RPS" or "LHT"
        lastFrame

        userPickName = "Unknown"
        userPickConf = 0
        compPickIdx = 1

        lhtAllowed
        lhtAllowedMask

        player1Symbol = "X"
        player2Symbol = "O"
        
        tttAllowed
        tttAllowedMask
        
        tttPred = "Unknown"
        tttConf = 0
        tttLastPred = "Unknown"
        tttStableCount = 0
        tttStableNeeded = 3
        
        tttCooldown = 0.8
        tttNextAllowedTime = 0
        tttTimer

        bcTimer
        bcUnknownThreshold = 0.70


    end
    
    methods (Access = private)
        
        function showScreen(app, panelToShow)
            app.MainMenuPanel.Visible = "off";
            app.RPSPanel.Visible = "off";
            app.BCPanel.Visible = "off";
            app.LHTPanel.Visible = "off";
            app.TTTPanel.Visible = "off";
            app.GestureGuidePanel.Visible = "off";

            panelToShow.Visible = "on";
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
                delete(app.tmr);
                app.tmr = [];
            end

            stopTTTTimer(app);
            stopBCTimer(app);


        end

        function setupRPS(app)
            appFolder = fileparts(mfilename("fullpath"));

            app.slideImgs = {fullfile(appFolder,"rock.png"), fullfile(appFolder,"paper.png"), fullfile(appFolder,"scissors.png")};

            app.finalImgs = app.slideImgs;

            if isempty(app.rpsNet)
                S = load("gesture_model_resnet18_14gestures.mat");
                app.rpsNet = S.trainedNet;
                app.rpsInputSize = S.inputSize;
                app.rpsClassNames = string(S.classes(:));

                app.rpsAllowed = ["Rock","Paper","Scissors"];
                app.rpsAllowedMask = ismember(app.rpsClassNames, app.rpsAllowed);
                if ~any(app.rpsAllowedMask)
                    app.CountdownLabel.Text = "Model does not include Rock Paper Scissors";
                end
            end

            if isempty(app.cam)
                try
                    app.cam = webcam;
                catch
                    app.CountdownLabel.Text = "Webcam not found";
                    return
                end
            end
        end

        function rpsTick(app)
            if ~isvalid(app)
                return
            end

            if app.showFinalNextTick
                app.postShootTimer = app.postShootTimer + app.tmr.Period;

                if app.postShootTimer >= 0.3

                    if app.currentMode == "RPS"
                        userName = "Unknown";
                        conf = 0;

                        if ~isempty(app.lastFrame) && ~isempty(app.rpsNet) && any(app.rpsAllowedMask)

                            img = imresize(app.lastFrame, app.rpsInputSize(1:2));
                            if size(img,3) == 1
                                img = repmat(img,1,1,3);
                            end

                            probs = predict(app.rpsNet, img);
                            probs = squeeze(probs(:));

                            masked = probs;
                            masked(~app.rpsAllowedMask) = 0;

                            s = sum(masked);
                            if s > 0
                                masked = masked / s;
                            end

                            [conf, idx] = max(masked);
                            predName = app.rpsClassNames(idx);

                            if conf >= app.rpsUnknownThreshold
                                userName = predName;
                            end
                        end
                    
                        compName = app.choices(app.compPickIdx);
                    
                        % Computer shows its move as the image
                        app.currentImageHandle.ImageSource = app.finalImgs{app.compPickIdx};
                    
                        % Text labels
                        app.ComputerChoiceLabel.Text = "Computer: " + compName;
                    
                        if userName == "Unknown"
                            app.UserChoiceLabel.Text = "You: Unknown (" + num2str(conf*100,"%.1f") + "%)";
                            app.ResultLabel.Text = "No clear gesture";
                        else
                            app.UserChoiceLabel.Text = "You: " + userName + " (" + num2str(conf*100,"%.1f") + "%)";
                    
                            % Decide result
                            if userName == compName
                                app.ResultLabel.Text = "Draw";
                            elseif (userName == "Rock" && compName == "Scissors") || ...
                                   (userName == "Paper" && compName == "Rock") || ...
                                   (userName == "Scissors" && compName == "Paper")
                                app.ResultLabel.Text = "You Win";
                            else
                                app.ResultLabel.Text = "You Lose";
                            end
                        end
                    
                    else
                        % LHT uses CV prediction + computer random move
                    
                        userName = "Unknown";
                        conf = 0;
                    
                        if ~isempty(app.lastFrame) && ~isempty(app.rpsNet) && any(app.lhtAllowedMask)
                    
                            img = imresize(app.lastFrame, app.rpsInputSize(1:2));
                            if size(img,3) == 1
                                img = repmat(img,1,1,3);
                            end
                    
                            probs = predict(app.rpsNet, img);app.CountdownLabel
                            probs = squeeze(probs(:));
                    
                            masked = probs;
                            masked(~app.lhtAllowedMask) = 0;
                    
                            s = sum(masked);
                            if s > 0
                                masked = masked / s;
                            end
                    
                            [conf, idx] = max(masked);
                            predName = app.rpsClassNames(idx);
                    
                            if conf >= app.rpsUnknownThreshold
                                userName = predName;
                            end
                        end
                    
                        compName = app.choices(app.compPickIdx);
                    
                        % Show computer move image
                        app.currentImageHandle.ImageSource = app.finalImgs{app.compPickIdx};
                    
                        % Labels
                        app.ComputerChoiceLabel2.Text = "Computer: " + compName;
                    
                        if userName == "Unknown"
                            app.UserChoiceLabel2.Text = "You: Unknown (" + num2str(conf*100,"%.1f") + "%)";
                            app.ResultLabel2.Text = "No clear gesture";
                        else
                            app.UserChoiceLabel2.Text = "You: " + userName + " (" + num2str(conf*100,"%.1f") + "%)";
                    
                            % Decide result for LHT
                            if userName == compName
                                app.ResultLabel2.Text = "Draw";
                            elseif (userName == "Lady"   && compName == "Hunter")  || ...
                                   (userName == "Tiger"  && compName == "Lady") || ...
                                   (userName == "Hunter" && compName == "Tiger")
                                app.ResultLabel2.Text = "You Win";
                            else
                                app.ResultLabel2.Text = "You Lose";
                            end
                        end
                    end


                    drawnow;

                    stop(app.tmr);
                    delete(app.tmr);
                    app.tmr = [];
                    app.showFinalNextTick = false;
                end

                return
            end

            if app.slideIdx > numel(app.slideImgs)
                app.slideIdx = 1;
            end

            app.currentImageHandle.ImageSource = app.slideImgs{app.slideIdx};

            app.slideIdx = app.slideIdx + 1;

            if ~isempty(app.cam) && isvalid(app.cam)
                frame = snapshot(app.cam);
                app.lastFrame = frame;
                imshow(frame, "Parent", app.currentAxesHandle);
            end

            app.countTimer = app.countTimer + app.tmr.Period;

            if app.countTimer >= 0.7
                app.countTimer = 0;

                app.currentLabelHandle.Text = app.countdownTexts(app.countIdx);
                drawnow;

                if app.countIdx == numel(app.countdownTexts)
                    app.showFinalNextTick = true;
                else
                app.countIdx = app.countIdx + 1;
                end
            end
        end


        %LHT
        function setupLHT(app)
            appFolder = fileparts(mfilename("fullpath"));
        
            app.slideImgs = {fullfile(appFolder,"lady.png"), ...
                             fullfile(appFolder,"hunter.png"), ...
                             fullfile(appFolder,"tiger.png")};
        
            app.finalImgs = app.slideImgs;
        
            % Load model once if not loaded yet
            if isempty(app.rpsNet)
                S = load("gesture_model_resnet18_14gestures.mat");
                app.rpsNet = S.trainedNet;
                app.rpsInputSize = S.inputSize;
                app.rpsClassNames = string(S.classes(:));
            end
        
            % LHT allowed classes
            app.lhtAllowed = ["Lady","Hunter","Tiger"];
            app.lhtAllowedMask = ismember(app.rpsClassNames, app.lhtAllowed);
        
            if ~any(app.lhtAllowedMask)
                app.CountDownLabel.Text = "Model does not include Lady Hunter Tiger";
            end
        
            % Webcam
            if isempty(app.cam)
                try
                    app.cam = webcam;
                catch
                    app.CountDownLabel.Text = "Webcam not found";
                    return
                end
            end
        end


        %ttt
        function initIfNeeded(app)
            if ~isempty(app.Buttons)
                return
            end

        app.Buttons = [
            app.B11, app.B12, app.B13
            app.B21, app.B22, app.B23
            app.B31, app.B32, app.B33
            ];
        end

        function resetGame(app)
            app.initIfNeeded();

            app.turn = 1;
            app.grid = zeros(3,3);
            app.gamemode = 1;

            for r = 1:3
                for c = 1:3
                    b = app.Buttons(r,c);
                    b.Text = "";
                    b.BackgroundColor = [0.15,0.15,0.15];
                    b.FontSize = 18;
                end
            end
            
            app.StatusLabel.Text = "Player 1 turn";
            app.TurnLamp.Color = [1 0 0];
        end

        function checkForWin(app)
            g = app.grid;

            lines = [
                g(1,1), g(1,2), g(1,3)
                g(2,1), g(2,2), g(2,3)
                g(3,1), g(3,2), g(3,3)
                g(1,1), g(2,1), g(3,1)
                g(1,2), g(2,2), g(3,2)
                g(1,3), g(2,3), g(3,3)
                g(1,1), g(2,2), g(3,3)
                g(1,3), g(2,2), g(3,1)
                ];

            if any(all(lines == 1,2))
                app.StatusLabel.Text = "Player 1 Wins";
                app.gamemode = 2;
                app.TurnLamp.Color = [0.8 0.8 0.8];
                return
            end
            if any(all(lines == 2,2))
                app.StatusLabel.Text = "Player 2 Wins";
                app.gamemode = 2;
                app.TurnLamp.Color = [0.8 0.8 0.8];
                return
            end
            if all(g(:) ~= 0)
                app.StatusLabel.Text = "Draw";
                app.gamemode = 2;
                app.TurnLamp.Color = [0.8 0.8 0.8];
            end
        end


        function makeMove(app,r,c)
            app.initIfNeeded();

            if app.gamemode == 2
                return
            end

            if app.grid(r,c) ~= 0 
                return
            end


            b = app.Buttons(r,c);

            if app.turn == 1
                b.Text = app.player1Symbol;
                b.FontSize = 55;
                b.BackgroundColor = [1 0 0];
                app.grid(r,c) = 1;
                app.turn = 2;
                app.TurnLamp.Color = [0 1 0];
                app.StatusLabel.Text = "Player 2 turn";
            else
                b.Text = app.player2Symbol;
                b.FontSize = 55;
                b.BackgroundColor = [0 1 0];
                app.grid(r,c) = 2;
                app.turn = 1;
                app.TurnLamp.Color = [1 0 0];
                app.StatusLabel.Text = "Player 1 turn";
            end

            app.checkForWin();
        end

                function name = predictUserRPS(app)
            name = "Unknown";

            if isempty(app.lastFrame) || isempty(app.rpsNet) || ~any(app.rpsAllowedMask)
                return
            end

            img = imresize(app.lastFrame, app.rpsInputSize(1:2));
            if size(img,3)==1
                img = repmat(img,1,1,3);
            end

            probs = predict(app.rpsNet, img);
            probs = squeeze(probs(:));

            masked = probs;
            masked(~app.rpsAllowedMask) = 0;

            s = sum(masked);
            if s <= 0
                return
            end

            masked = masked / s;

            [conf, idx] = max(masked);
            predName = app.rpsClassNames(idx);

            app.userPickConf = conf;

            if conf < app.rpsUnknownThreshold
                name = "Unknown";
            else
                name = predName;
            end
        end

        function result = decideRPS(app, userName, compName)
            if userName == "Unknown"
                result = "Show a clear Rock Paper or Scissors";
                return
            end

            if userName == compName
                result = "Draw";
                return
            end

            if (userName == "Rock"     && compName == "Scissors") || ...
               (userName == "Paper"    && compName == "Rock")     || ...
               (userName == "Scissors" && compName == "Paper")
                result = "You Win";
            else
                result = "You Lose";
            end
        end


        %ttt
        function setupTTT(app)
            if isempty(app.rpsNet)
                S = load("gesture_model_resnet18_14gestures.mat");
                app.rpsNet = S.trainedNet;
                app.rpsInputSize = S.inputSize;
                app.rpsClassNames = string(S.classes(:));
            end
        
            app.tttAllowed = ["X","O"];
            app.tttAllowedMask = ismember(app.rpsClassNames, app.tttAllowed);
        
            if ~any(app.tttAllowedMask)
                uialert(app.UIFigure, "Model does not include X and O classes. Check O vs 0 in your model.", "Model Error");
            end
        
            if isempty(app.cam)
                try
                    app.cam = webcam;
                catch
                    uialert(app.UIFigure, "Webcam not found", "Webcam Error");
                end
            end
        end

        function [pred, conf] = predictTTT(app)
            pred = "Unknown";
            conf = 0;
        
            if isempty(app.lastFrame) || isempty(app.rpsNet) || ~any(app.tttAllowedMask)
                return
            end
        
            img = imresize(app.lastFrame, app.rpsInputSize(1:2));
            if size(img,3) == 1
                img = repmat(img,1,1,3);
            end
        
            probs = predict(app.rpsNet, img);
            probs = squeeze(probs(:));
        
            masked = probs;
            masked(~app.tttAllowedMask) = 0;
        
            s = sum(masked);
            if s <= 0
                return
            end
            masked = masked / s;
        
            [conf, idx] = max(masked);
            predName = app.rpsClassNames(idx);
        
            if predName == "0"
                predName = "O";
            end
        
            if conf >= app.rpsUnknownThreshold
                pred = predName;
            end
        end

        function tttTick(app)
            if ~isvalid(app) || app.TTTPanel.Visible ~= "on"
                return
            end
        
            if ~isempty(app.cam) && isvalid(app.cam)
                frame = snapshot(app.cam);
                app.lastFrame = frame;
            else
                return
            end
        
            [pred, conf] = predictTTT(app);
        
            if pred == app.tttLastPred
                app.tttStableCount = app.tttStableCount + 1;
            else
                app.tttStableCount = 1;
                app.tttLastPred = pred;
            end
        
            if pred == "Unknown"
                app.StatusLabel.Text = "Show X or O to choose Player 1";
                return
            end
        
            app.StatusLabel.Text = "Seeing: " + pred + " (" + num2str(conf*100,"%.0f") + "%)";
        
            if app.tttStableCount >= app.tttStableNeeded
                app.player1Symbol = pred;
                if app.player1Symbol == "X"
                    app.player2Symbol = "O";
                else
                    app.player2Symbol = "X";
                end
        
                stopTTTTimer(app);
        
                app.resetGame();
                app.StatusLabel.Text = "Player 1 is " + app.player1Symbol + ". Click squares to play";
            end
        end


        function startTTTTimer(app)
            if ~isempty(app.tttTimer) && isvalid(app.tttTimer)
                stop(app.tttTimer);
                delete(app.tttTimer);
            end
        
            app.tttTimer = timer("ExecutionMode","fixedRate", ...
                "Period",0.12, ...
                "BusyMode","drop", ...
                "TimerFcn",@(~,~) tttTick(app));
        
            start(app.tttTimer);
        end
        
        function stopTTTTimer(app)
            if ~isempty(app.tttTimer) && isvalid(app.tttTimer)
                stop(app.tttTimer);
                delete(app.tttTimer);
                app.tttTimer = [];
            end
        end

        function setupBC(app)
            if isempty(app.rpsNet)
                S = load("gesture_model_resnet18_14gestures.mat");
                app.rpsNet = S.trainedNet;
                app.rpsInputSize = S.inputSize;
                app.rpsClassNames = string(S.classes(:));
            end
        
            if isempty(app.cam)
                try
                    app.cam = webcam;
                catch
                    uialert(app.UIFigure, "Webcam not found", "Webcam Error");
                end
            end
        end

        function startBCTimer(app)
            setupBC(app);
        
            if ~isempty(app.bcTimer) && isvalid(app.bcTimer)
                stop(app.bcTimer);
                delete(app.bcTimer);
            end
        
            app.bcTimer = timer("ExecutionMode","fixedRate", ...
                "Period",0.12, ...
                "BusyMode","drop", ...
                "TimerFcn",@(~,~) bcTick(app));
        
            start(app.bcTimer);
        end
        
        function stopBCTimer(app)
            if ~isempty(app.bcTimer) && isvalid(app.bcTimer)
                stop(app.bcTimer);
                delete(app.bcTimer);
                app.bcTimer = [];
            end
        end

        function bcTick(app)
            if ~isvalid(app) || app.BCPanel.Visible ~= "on"
                return
            end
        
            if ~isempty(app.cam) && isvalid(app.cam)
                frame = snapshot(app.cam);
                app.lastFrame = frame;
                imshow(frame, "Parent", app.LiveAxes3);
            else
                return
            end
        
            img = imresize(app.lastFrame, app.rpsInputSize(1:2));
            if size(img,3) == 1
                img = repmat(img,1,1,3);
            end
        
            probs = predict(app.rpsNet, img);
            probs = squeeze(probs(:));
        
            [conf, idx] = max(probs);
            predName = app.rpsClassNames(idx);
        
            if conf < app.bcUnknownThreshold
                predDisplay = "Unknown";
            else
                predDisplay = predName;
            end
        
            if isprop(app,"BCStatusLabel") && isvalid(app.BCStatusLabel)
                app.BCStatusLabel.Text = "Prediction: " + predDisplay + " (" + num2str(conf*100,"%.1f") + "%)";
            end
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function ButtonPushed(app, event)
            showScreen(app, app.RPSPanel);
        end

        % Button pushed function: ReturnToMainMenuButton_5
        function ReturnToMainMenuButtonPushed(app, event)
            showScreen(app, app.MainMenuPanel);
        end

        % Button pushed function: RPSButton
        function RPSButtonPushed(app, event)
            showScreen(app, app.RPSPanel);
        end

        % Button pushed function: BCButton
        function BCButtonPushed(app, event)
            showScreen(app, app.BCPanel);
            startBCTimer(app);
        end

        % Button pushed function: ReturnToMainMenuButton_2
        function ReturnToMainMenuButton_2Pushed(app, event)
            showScreen(app, app.MainMenuPanel);
        end

        % Button pushed function: ReturnToMainMenuButton_3
        function ReturnToMainMenuButton_3Pushed(app, event)
            showScreen(app, app.MainMenuPanel);
        end

        % Button pushed function: LHTButton
        function LHTButtonPushed(app, event)
            showScreen(app, app.LHTPanel);
        end

        % Button pushed function: TTTButton
        function TTTButtonPushed(app, event)
            showScreen(app, app.TTTPanel);

            setupTTT(app);
        
            app.player1Symbol = "";
            app.player2Symbol = "";
        
            app.tttStableCount = 0;
            app.tttLastPred = "Unknown";
        
            app.StatusLabel.Text = "Show X or O to choose Player 1";
        
            startTTTTimer(app);

        end

        % Button pushed function: ReturnToMainMenuButton_4
        function ReturnToMainMenuButton_4Pushed(app, event)
            showScreen(app, app.MainMenuPanel);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           
            setupRPS(app);
            app.currentMode = "RPS";

            app.step = 0;
            app.slideIdx = randi(numel(app.slideImgs));
            app.currentImageHandle = app.SymbolImage;
            app.currentLabelHandle = app.CountdownLabel;
            app.currentAxesHandle = app.LiveAxes;
            app.choices = ["Rock","Paper","Scissors"];
            app.compPickIdx = randi(3);
            app.ComputerChoiceLabel.Text = "Computer: ";
            app.UserChoiceLabel.Text = "You: ";
            app.ResultLabel.Text = "";


            app.countdownTexts = ["Rock...","Paper...","Scissors...","Shoot!"];
            app.countIdx = 1;
            app.countTimer = 0;
            app.showFinalNextTick = false;
            app.postShootTimer = 0;

            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
                delete(app.tmr);
            end

            app.tmr = timer("ExecutionMode","fixedRate","Period",0.08,"BusyMode","drop","TimerFcn",@(~,~) rpsTick(app));

            start(app.tmr);


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ComputerChoiceLabel.Text = "Computer: ";
            app.UserChoiceLabel.Text = "You: ";
            app.ResultLabel.Text = "";

            
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
                delete(app.tmr);
                app.tmr = [];
            end

            app.step = 0;
            app.slideIdx = 1;

            app.CountdownLabel.Text = "Press Start";

            if ~isempty(app.slideImgs)
                app.SymbolImage.ImageSource = app.slideImgs{1};
            end

            if ~isempty(app.cam)
                frame = snapshot(app.cam);
                imshow(frame,"Parent",app.LiveAxes)
            end

        end

        % Button pushed function: StartButton2
        function StartButton2Pushed(app, event)
            setupLHT(app);
            app.currentMode = "LHT";

            app.currentImageHandle = app.SymbolImage2;
            app.currentLabelHandle = app.CountDownLabel;   % from LHTPanel
            app.currentAxesHandle = app.LiveAxes2;
            app.choices = ["Lady","Hunter","Tiger"];
            app.compPickIdx = randi(3);

            app.ComputerChoiceLabel2.Text = "Computer: ";
            app.UserChoiceLabel2.Text = "You: ";
            app.ResultLabel2.Text = "";


            app.slideIdx = randi(numel(app.slideImgs));

            app.countdownTexts = ["Lady...","Hunter...","Tiger...","Shoot!"];
            app.countIdx = 1;
            app.countTimer = 0;

            app.showFinalNextTick = false;
            app.postShootTimer = 0;


            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
                delete(app.tmr);
            end

            app.tmr = timer("ExecutionMode","fixedRate","Period",0.08, "BusyMode","drop","TimerFcn",@(~,~) rpsTick(app));

            start(app.tmr);

        end

        % Button pushed function: B11
        function B11ButtonPushed(app, event)
            app.makeMove(1,1);
        end

        % Button pushed function: B12
        function B12ButtonPushed(app, event)
            app.makeMove(1,2);
        end

        % Button pushed function: B13
        function B13ButtonPushed(app, event)
            app.makeMove(1,3);
        end

        % Button pushed function: B21
        function B21ButtonPushed(app, event)
            app.makeMove(2,1);
        end

        % Button pushed function: B22
        function B22ButtonPushed(app, event)
            app.makeMove(2,2);
        end

        % Button pushed function: B23
        function B23ButtonPushed(app, event)
            app.makeMove(2,3);
        end

        % Button pushed function: B31
        function B31ButtonPushed(app, event)
            app.makeMove(3,1);
        end

        % Button pushed function: B32
        function B32ButtonPushed(app, event)
            app.makeMove(3,2);
        end

        % Button pushed function: B33
        function B33ButtonPushed(app, event)
            app.makeMove(3,3);
        end

        % Button pushed function: ResetButton3
        function ResetButton3Pushed(app, event)
            app.resetGame()
        end

        % Button pushed function: ResetButton2
        function ResetButton2Pushed(app, event)
            app.ComputerChoiceLabel2.Text = "Computer: ";
            app.UserChoiceLabel2.Text = "You: ";
            app.ResultLabel2.Text = "";
        
            if ~isempty(app.tmr) && isvalid(app.tmr)
                stop(app.tmr);
                delete(app.tmr);
                app.tmr = [];
            end
        
            app.CountDownLabel.Text = "Press Start";
        
            if ~isempty(app.slideImgs)
                app.SymbolImage2.ImageSource = app.slideImgs{1};
            end
        
            if ~isempty(app.cam)
                frame = snapshot(app.cam);
                imshow(frame,"Parent",app.LiveAxes2)
            end
        end

        % Button pushed function: GestureGuideButton
        function GestureGuideButtonPushed(app, event)
            showScreen(app, app.GestureGuidePanel)
        end

        % Button pushed function: ReturntoGesureRecogniserButton
        function ReturntoGesureRecogniserButtonPushed(app, event)
            showScreen(app, app.BCPanel)
        end

        % Button pushed function: ReturnToMainMenuButton_6
        function ReturnToMainMenuButton_6Pushed(app, event)
            showScreen(app, app.MainMenuPanel)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LHTPanel = uipanel(app.UIFigure);
            app.LHTPanel.Title = 'Lady Hunter Tiger';
            app.LHTPanel.Visible = 'off';
            app.LiveAxes2 = uiaxes(app.LHTPanel);
            app.ReturnToMainMenuButton_3 = uibutton(app.LHTPanel, 'push');
            app.ReturnToMainMenuButton_3.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMainMenuButton_3Pushed, true);
            app.ReturnToMainMenuButton_3.Text = 'Return To Main Menu';
            app.SymbolImage2 = uiimage(app.LHTPanel);
            app.StartButton2 = uibutton(app.LHTPanel, 'push');
            app.StartButton2.ButtonPushedFcn = createCallbackFcn(app, @StartButton2Pushed, true);
            app.StartButton2.Text = 'Start';
            app.ResetButton2 = uibutton(app.LHTPanel, 'push');
            app.ResetButton2.ButtonPushedFcn = createCallbackFcn(app, @ResetButton2Pushed, true);
            app.ResetButton2.Text = 'Reset';
            app.CountDownLabel = uilabel(app.LHTPanel);
            app.CountDownLabel.Text = '';
            app.ComputerChoiceLabel2 = uilabel(app.LHTPanel);
            app.ComputerChoiceLabel2.Text = 'Computer:';
            app.UserChoiceLabel2 = uilabel(app.LHTPanel);
            app.UserChoiceLabel2.Text = 'You:';
            app.ResultLabel2 = uilabel(app.LHTPanel);
            app.ResultLabel2.Text = '';
            app.RPSPanel = uipanel(app.UIFigure);
            app.RPSPanel.Title = 'Rock Paper Scissor';
            app.RPSPanel.Visible = 'off';
            app.LiveAxes = uiaxes(app.RPSPanel);
            app.ReturnToMainMenuButton_5 = uibutton(app.RPSPanel, 'push');
            app.ReturnToMainMenuButton_5.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMainMenuButtonPushed, true);
            app.ReturnToMainMenuButton_5.Text = 'Return To Main Menu';
            app.StartButton = uibutton(app.RPSPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.ResetButton = uibutton(app.RPSPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.CountdownLabel = uilabel(app.RPSPanel);
            app.CountdownLabel.Text = '';
            app.SymbolImage = uiimage(app.RPSPanel);
            app.ComputerChoiceLabel = uilabel(app.RPSPanel);
            app.ComputerChoiceLabel.Text = 'Computer: ';
            app.UserChoiceLabel = uilabel(app.RPSPanel);
            app.UserChoiceLabel.Text = 'You: ';
            app.ResultLabel = uilabel(app.RPSPanel);
            app.ResultLabel.Text = '';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Select Game';
            app.RPSButton = uibutton(app.MainMenuPanel, 'push');
            app.RPSButton.ButtonPushedFcn = createCallbackFcn(app, @RPSButtonPushed, true);
            app.RPSButton.Icon = fullfile(pathToMLAPP, 'rock_paper_scissors.png');
            app.RPSButton.Text = '';
            app.BCButton = uibutton(app.MainMenuPanel, 'push');
            app.BCButton.ButtonPushedFcn = createCallbackFcn(app, @BCButtonPushed, true);
            app.BCButton.Icon = fullfile(pathToMLAPP, 'gesturerecog.png');
            app.BCButton.Text = '';
            app.LHTButton = uibutton(app.MainMenuPanel, 'push');
            app.LHTButton.ButtonPushedFcn = createCallbackFcn(app, @LHTButtonPushed, true);
            app.LHTButton.Icon = fullfile(pathToMLAPP, 'lady_hunter_tiger.png');
            app.LHTButton.Text = '';
            app.TTTButton = uibutton(app.MainMenuPanel, 'push');
            app.TTTButton.ButtonPushedFcn = createCallbackFcn(app, @TTTButtonPushed, true);
            app.TTTButton.Icon = fullfile(pathToMLAPP, 'tic_tac_toe.png');
            app.TTTButton.Text = '';
            app.TTTPanel = uipanel(app.UIFigure);
            app.TTTPanel.Title = 'Tic Tac Toe';
            app.TTTPanel.Visible = 'off';
            app.ReturnToMainMenuButton_4 = uibutton(app.TTTPanel, 'push');
            app.ReturnToMainMenuButton_4.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMainMenuButton_4Pushed, true);
            app.ReturnToMainMenuButton_4.Text = 'Return To Main Menu';
            app.B11 = uibutton(app.TTTPanel, 'push');
            app.B11.ButtonPushedFcn = createCallbackFcn(app, @B11ButtonPushed, true);
            app.B11.Text = '';
            app.B12 = uibutton(app.TTTPanel, 'push');
            app.B12.ButtonPushedFcn = createCallbackFcn(app, @B12ButtonPushed, true);
            app.B12.Text = '';
            app.B13 = uibutton(app.TTTPanel, 'push');
            app.B13.ButtonPushedFcn = createCallbackFcn(app, @B13ButtonPushed, true);
            app.B13.Text = '';
            app.B21 = uibutton(app.TTTPanel, 'push');
            app.B21.ButtonPushedFcn = createCallbackFcn(app, @B21ButtonPushed, true);
            app.B21.Text = '';
            app.B22 = uibutton(app.TTTPanel, 'push');
            app.B22.ButtonPushedFcn = createCallbackFcn(app, @B22ButtonPushed, true);
            app.B22.Text = '';
            app.B23 = uibutton(app.TTTPanel, 'push');
            app.B23.ButtonPushedFcn = createCallbackFcn(app, @B23ButtonPushed, true);
            app.B23.Text = '';
            app.B31 = uibutton(app.TTTPanel, 'push');
            app.B31.ButtonPushedFcn = createCallbackFcn(app, @B31ButtonPushed, true);
            app.B31.Text = '';
            app.B32 = uibutton(app.TTTPanel, 'push');
            app.B32.ButtonPushedFcn = createCallbackFcn(app, @B32ButtonPushed, true);
            app.B32.Text = '';
            app.B33 = uibutton(app.TTTPanel, 'push');
            app.B33.ButtonPushedFcn = createCallbackFcn(app, @B33ButtonPushed, true);
            app.B33.Text = '';
            app.Label = uilabel(app.TTTPanel);
            app.Label.Text = '';
            app.TurnLamp = uilamp(app.TTTPanel);
            app.StatusLabel = uilabel(app.TTTPanel);
            app.StatusLabel.Text = '';
            app.ResetButton3 = uibutton(app.TTTPanel, 'push');
            app.ResetButton3.ButtonPushedFcn = createCallbackFcn(app, @ResetButton3Pushed, true);
            app.ResetButton3.Text = 'Reset';
            app.BCPanel = uipanel(app.UIFigure);
            app.BCPanel.Title = 'Gesture Recogniser';
            app.BCPanel.Visible = 'off';
            app.LiveAxes3 = uiaxes(app.BCPanel);
            app.ReturnToMainMenuButton_2 = uibutton(app.BCPanel, 'push');
            app.ReturnToMainMenuButton_2.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMainMenuButton_2Pushed, true);
            app.ReturnToMainMenuButton_2.Text = 'Return To Main Menu';
            app.BCStatusLabel = uilabel(app.BCPanel);
            app.BCStatusLabel.Text = 'Prediction:';
            app.GestureGuideButton = uibutton(app.BCPanel, 'push');
            app.GestureGuideButton.ButtonPushedFcn = createCallbackFcn(app, @GestureGuideButtonPushed, true);
            app.GestureGuideButton.Text = 'Gesture Guide';
            app.GestureGuidePanel = uipanel(app.UIFigure);
            app.GestureGuidePanel.Title = 'Gesture Guide';
            app.ReturnToMainMenuButton_6 = uibutton(app.GestureGuidePanel, 'push');
            app.ReturnToMainMenuButton_6.ButtonPushedFcn = createCallbackFcn(app, @ReturnToMainMenuButton_6Pushed, true);
            app.ReturnToMainMenuButton_6.Text = 'Return To Main Menu';
            app.ReturntoGesureRecogniserButton = uibutton(app.GestureGuidePanel, 'push');
            app.ReturntoGesureRecogniserButton.ButtonPushedFcn = createCallbackFcn(app, @ReturntoGesureRecogniserButtonPushed, true);
            app.ReturntoGesureRecogniserButton.Text = 'Return to Gesure Recogniser';
            app.Image = uiimage(app.GestureGuidePanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'timeouthand.png');
            app.Image_2 = uiimage(app.GestureGuidePanel);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'tigerhand.png');
            app.Image_7 = uiimage(app.GestureGuidePanel);
            app.Image_7.ImageSource = fullfile(pathToMLAPP, 'thumbhand.png');
            app.Image_12 = uiimage(app.GestureGuidePanel);
            app.Image_12.ImageSource = fullfile(pathToMLAPP, 'rockonhand2.png');
            app.Image_13 = uiimage(app.GestureGuidePanel);
            app.Image_13.ImageSource = fullfile(pathToMLAPP, 'Xhand.png');
            app.Image_14 = uiimage(app.GestureGuidePanel);
            app.Image_14.ImageSource = fullfile(pathToMLAPP, 'palmhand.png');
            app.Image_15 = uiimage(app.GestureGuidePanel);
            app.Image_15.ImageSource = fullfile(pathToMLAPP, 'prayhand.png');
            app.Image_16 = uiimage(app.GestureGuidePanel);
            app.Image_16.ImageSource = fullfile(pathToMLAPP, 'Scissorshand.png');
            app.Image_17 = uiimage(app.GestureGuidePanel);
            app.Image_17.ImageSource = fullfile(pathToMLAPP, 'rockhand.png');
            app.Image_18 = uiimage(app.GestureGuidePanel);
            app.Image_18.ImageSource = fullfile(pathToMLAPP, 'paperhand.png');
            app.Image_19 = uiimage(app.GestureGuidePanel);
            app.Image_19.ImageSource = fullfile(pathToMLAPP, 'hunterhand.png');
            app.Image_20 = uiimage(app.GestureGuidePanel);
            app.Image_20.ImageSource = fullfile(pathToMLAPP, 'Ohand.png');
            app.Image_22 = uiimage(app.GestureGuidePanel);
            app.Image_22.ImageSource = fullfile(pathToMLAPP, 'hearthand.png');
            app.Image_23 = uiimage(app.GestureGuidePanel);
            app.Image_23.ImageSource = fullfile(pathToMLAPP, 'ladyhand.png');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ES3H3Group2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end