classdef es3h3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        tttpanel       matlab.ui.container.Panel
        lhtpanel       matlab.ui.container.Panel
        bcpanel        matlab.ui.container.Panel
        rpspanel       matlab.ui.container.Panel
        MainMenuPanel  matlab.ui.container.Panel
        bcbutton       matlab.ui.control.Button
        lhtbutton      matlab.ui.control.Button
        tttbutton      matlab.ui.control.Button
        rpsbutton      matlab.ui.control.Button
        lhtim          matlab.ui.control.Image
        tttimage       matlab.ui.control.Image
        bcimage        matlab.ui.control.Image
        rpsimage       matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function showScreen(app,panelToShow)
            app.MainMenuPanel.Visble = "off";
            app.rpspanel.Visible = "off";
            app.bcpanel.Visible = "off";
            app.tttpanel.Visible = "off";
            app.lhtpanel.Visible = "off";

            panelToShow.Visible = "on";            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: rpsbutton
        function rpsbuttonButtonPushed(app, event)
            showScreen(app, app.rpspanel);
        end

        % Button pushed function: bcbutton
        function bcbuttonButtonPushed(app, event)
            showScreen(app, app.bcpanel);
        end

        % Callback function: not associated with a component
        function lhtimageImageClicked(app, event)
            
        end

        % Button pushed function: lhtbutton
        function lhtbuttonButtonPushed(app, event)
            showScreen(app, app.lhtpanel);
        end

        % Callback function: not associated with a component
        function tttbuttonButtonPushed(app, event)
            showScreen(app, app.tttpanel);
        end

        % Button pushed function: tttbutton
        function tttbuttonButtonPushed2(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MainMenuPanel = uipanel(app.UIFigure);
            app.MainMenuPanel.Title = 'Choose Game';
            app.rpsimage = uiimage(app.MainMenuPanel);
            app.rpsimage.ImageSource = fullfile(pathToMLAPP, 'rock_paper_scissors.png');
            app.bcimage = uiimage(app.MainMenuPanel);
            app.bcimage.ImageSource = fullfile(pathToMLAPP, 'background_changer.png');
            app.tttimage = uiimage(app.MainMenuPanel);
            app.tttimage.ImageSource = fullfile(pathToMLAPP, 'tic_tac_toe.png');
            app.lhtim = uiimage(app.MainMenuPanel);
            app.lhtim.ImageSource = fullfile(pathToMLAPP, 'lady_hunter_tiger.png');
            app.rpsbutton = uibutton(app.MainMenuPanel, 'push');
            app.rpsbutton.ButtonPushedFcn = createCallbackFcn(app, @rpsbuttonButtonPushed, true);
            app.rpsbutton.Visible = 'off';
            app.rpsbutton.Text = '';
            app.tttbutton = uibutton(app.MainMenuPanel, 'push');
            app.tttbutton.ButtonPushedFcn = createCallbackFcn(app, @tttbuttonButtonPushed2, true);
            app.tttbutton.Visible = 'off';
            app.tttbutton.Text = '';
            app.lhtbutton = uibutton(app.MainMenuPanel, 'push');
            app.lhtbutton.ButtonPushedFcn = createCallbackFcn(app, @lhtbuttonButtonPushed, true);
            app.lhtbutton.Visible = 'off';
            app.lhtbutton.Text = '';
            app.bcbutton = uibutton(app.MainMenuPanel, 'push');
            app.bcbutton.ButtonPushedFcn = createCallbackFcn(app, @bcbuttonButtonPushed, true);
            app.bcbutton.Visible = 'off';
            app.bcbutton.Text = '';
            app.rpspanel = uipanel(app.UIFigure);
            app.rpspanel.Title = 'Rock Paper Scissors';
            app.rpspanel.Visible = 'off';
            app.bcpanel = uipanel(app.UIFigure);
            app.bcpanel.Title = 'Background Changer';
            app.bcpanel.Visible = 'off';
            app.lhtpanel = uipanel(app.UIFigure);
            app.lhtpanel.Title = 'Lady Hunter Tiger';
            app.lhtpanel.Visible = 'off';
            app.tttpanel = uipanel(app.UIFigure);
            app.tttpanel.Title = 'Tic Tac Toe';
            app.tttpanel.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = es3h3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end