classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Panel3    matlab.ui.container.Panel
        Panel2    matlab.ui.container.Panel
        Panel     matlab.ui.container.Panel
    end

    
    properties (Access = private)
        cam
        tmr
        step = 0
        slideIdx = 1
        slideImgs
        finalImgs
        frozenFrame
    end
    
    methods (Access = private)
        
        function initializeApp(app)
            app.Panel.Visible = "on";
            app.Panel2.Visible = "off";
            app.Panel3.Visible = "off";
        end

        function setupRPS(app)
            app.slideImgs = {"rock.png","paper.png","scissors.png"};
            app.finalImgs = app.slideImgs;

            if isempty(app.cam)
                try
                    app.cam = webcam;
                catch
                    app.CountdownLabel.T
    end
    

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.Panel2 = uipanel(app.UIFigure);
            app.Panel2.Title = 'Panel2';
            app.Panel3 = uipanel(app.UIFigure);
            app.Panel3.Title = 'Panel3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end