classdef tictacto_cv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SelectionLabel     matlab.ui.control.Label
        GestureLabel       matlab.ui.control.Label
        StopCameraButton   matlab.ui.control.Button
        StartCameraButton  matlab.ui.control.Button
        NewGameButton      matlab.ui.control.Button
        StatusLabel        matlab.ui.control.Label
        TurnLamp           matlab.ui.control.Lamp
        Label              matlab.ui.control.Label
        B33                matlab.ui.control.Button
        B32                matlab.ui.control.Button
        B31                matlab.ui.control.Button
        B23                matlab.ui.control.Button
        B22                matlab.ui.control.Button
        B21                matlab.ui.control.Button
        B13                matlab.ui.control.Button
        B12                matlab.ui.control.Button
        B11                matlab.ui.control.Button
        CameraAxes         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        turn = 1
        grid = zeros(3,3)
        gamemode = 1

        Buttons matlab.ui.control.Button
    end

    methods (Access = private)

        function initIfNeeded(app)
            if ~isempty(app.Buttons)
                return
            end

        app.Buttons = [
            app.B11, app.B12, app.B13
            app.B21, app.B22, app.B23
            app.B31, app.B32, app.B33
            ];
        end

        function resetGame(app)
            app.initIfNeeded();

            app.turn = 1;
            app.grid = zeros(3,3);
            app.gamemode = 1;

            for r = 1:3
                for c = 1:3
                    b = app.Buttons(r,c);
                    b.Text = "";
                    b.BackgroundColor = [1 1 1];
                    b.FontSize = 18;
                end
            end
            
            app.StatusLabel.Text = "Player 1 turn";
            app.TurnLamp.Color = [1 0 0];
        end

        function checkForWin(app)
            g = app.grid;

            lines = [
                g(1,1), g(1,2), g(1,3)
                g(2,1), g(2,2), g(2,3)
                g(3,1), g(3,2), g(3,3)
                g(1,1), g(2,1), g(3,1)
                g(1,2), g(2,2), g(3,2)
                g(1,3), g(2,3), g(3,3)
                g(1,1), g(2,2), g(3,3)
                g(1,3), g(2,2), g(3,1)
                ];

            if any(all(lines == 1,2))
                app.StatusLabel.Text = "Player 1 Wins";
                app.gamemode = 2;
                app.TurnLamp.Color = [0.8 0.8 0.8];
                return
            end
            if any(all(lines == 2,2))
                app.StatusLabel.Text = "Player 2 Wins";
                app.gamemode = 2;
                app.TurnLamp.Color = [0.8 0.8 0.8];
                return
            end
            if all(g(:) ~= 0)
                app.StatusLabel.Text = "Draw";
                app.gamemode = 2;
                app.TurnLamp.Color = [0.8 0.8 0.8];
            end
        end


        function makeMove(app,r,c)
            app.initIfNeeded();

            if app.gamemode == 2
                return
            end

            if app.grid(r,c) ~= 0 
                return
            end

            b = app.Buttons(r,c);

            if app.turn == 1
                b.Text = "X";
                b.FontSize = 55;
                b.BackgroundColor = [1 0 0];
                app.grid(r,c) = 1;
                app.turn = 2;
                app.TurnLamp.Color = [0 1 0];
                app.StatusLabel.Text = "Player 2 turn";
            else
                b.Text = "O";
                b.FontSize = 55;
                b.BackgroundColor = [0 1 0];
                app.grid(r,c) = 2;
                app.turn = 1;
                app.TurnLamp.Color = [1 0 0];
                app.StatusLabel.Text = "Player 1 turn";
            end

            app.checkForWin();
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: B11
        function B11ButtonPushed(app, event)
            app.makeMove(1,1);
        end

        % Button pushed function: B12
        function B12ButtonPushed(app, event)
            app.makeMove(1,2);
        end

        % Button pushed function: B13
        function B13ButtonPushed(app, event)
            app.makeMove(1,3);
        end

        % Button pushed function: B21
        function B21ButtonPushed(app, event)
            app.makeMove(2,1);
        end

        % Button pushed function: B22
        function B22ButtonPushed(app, event)
            app.makeMove(2,2);
        end

        % Button pushed function: B23
        function B23ButtonPushed(app, event)
            app.makeMove(2,3);
        end

        % Button pushed function: B31
        function B31ButtonPushed(app, event)
            app.makeMove(3,1);
        end

        % Button pushed function: B32
        function B32ButtonPushed(app, event)
            app.makeMove(3,2);
        end

        % Button pushed function: B33
        function B33ButtonPushed(app, event)
            app.makeMove(3,3);
        end

        % Button pushed function: NewGameButton
        function NewGameButtonPushed(app, event)
            app.resetGame();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CameraAxes = uiaxes(app.UIFigure);
            title(app.CameraAxes, 'Title')
            xlabel(app.CameraAxes, 'X')
            ylabel(app.CameraAxes, 'Y')
            zlabel(app.CameraAxes, 'Z')
            app.B11 = uibutton(app.UIFigure, 'push');
            app.B11.ButtonPushedFcn = createCallbackFcn(app, @B11ButtonPushed, true);
            app.B11.Text = '';
            app.B12 = uibutton(app.UIFigure, 'push');
            app.B12.ButtonPushedFcn = createCallbackFcn(app, @B12ButtonPushed, true);
            app.B12.Text = '';
            app.B13 = uibutton(app.UIFigure, 'push');
            app.B13.ButtonPushedFcn = createCallbackFcn(app, @B13ButtonPushed, true);
            app.B13.Text = '';
            app.B21 = uibutton(app.UIFigure, 'push');
            app.B21.ButtonPushedFcn = createCallbackFcn(app, @B21ButtonPushed, true);
            app.B21.Text = '';
            app.B22 = uibutton(app.UIFigure, 'push');
            app.B22.ButtonPushedFcn = createCallbackFcn(app, @B22ButtonPushed, true);
            app.B22.Text = '';
            app.B23 = uibutton(app.UIFigure, 'push');
            app.B23.ButtonPushedFcn = createCallbackFcn(app, @B23ButtonPushed, true);
            app.B23.Text = '';
            app.B31 = uibutton(app.UIFigure, 'push');
            app.B31.ButtonPushedFcn = createCallbackFcn(app, @B31ButtonPushed, true);
            app.B31.Text = '';
            app.B32 = uibutton(app.UIFigure, 'push');
            app.B32.ButtonPushedFcn = createCallbackFcn(app, @B32ButtonPushed, true);
            app.B32.Text = '';
            app.B33 = uibutton(app.UIFigure, 'push');
            app.B33.ButtonPushedFcn = createCallbackFcn(app, @B33ButtonPushed, true);
            app.B33.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.TurnLamp = uilamp(app.UIFigure);
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Player 1 Turn';
            app.NewGameButton = uibutton(app.UIFigure, 'push');
            app.NewGameButton.ButtonPushedFcn = createCallbackFcn(app, @NewGameButtonPushed, true);
            app.NewGameButton.Text = 'New Game';
            app.StartCameraButton = uibutton(app.UIFigure, 'push');
            app.StartCameraButton.Text = 'Start Camera';
            app.StopCameraButton = uibutton(app.UIFigure, 'push');
            app.StopCameraButton.Text = 'Stop Camera';
            app.GestureLabel = uilabel(app.UIFigure);
            app.GestureLabel.Text = 'Gesture: none';
            app.SelectionLabel = uilabel(app.UIFigure);
            app.SelectionLabel.Text = 'Selection Label';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tictacto_cv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end